classdef CapacitorStatics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        CapacitorTypeButtonGroup        matlab.ui.container.ButtonGroup
        ParallelPlateButton             matlab.ui.control.RadioButton
        CylindricalButton               matlab.ui.control.RadioButton
        SphericalButton                 matlab.ui.control.RadioButton
        DistancePCSEditFieldLabel       matlab.ui.control.Label
        DistancePCSEditField            matlab.ui.control.NumericEditField
        VoltagePCSLabel                 matlab.ui.control.Label
        VoltagePCSEditField             matlab.ui.control.NumericEditField
        LengthPEditFieldLabel           matlab.ui.control.Label
        LengthPEditField                matlab.ui.control.NumericEditField
        WidthPEditFieldLabel            matlab.ui.control.Label
        WidthPEditField                 matlab.ui.control.NumericEditField
        HeightCEditFieldLabel           matlab.ui.control.Label
        HeightCEditField                matlab.ui.control.NumericEditField
        InnerRadiusCSEditFieldLabel     matlab.ui.control.Label
        InnerRadiusCSEditField          matlab.ui.control.NumericEditField
        OuterRadiusCSEditFieldLabel     matlab.ui.control.Label
        OuterRadiusCSEditField          matlab.ui.control.NumericEditField
        GetValuesButton                 matlab.ui.control.Button
        CapacitanceEditFieldLabel       matlab.ui.control.Label
        CapacitanceEditField            matlab.ui.control.NumericEditField
        ConductanceEditFieldLabel       matlab.ui.control.Label
        ConductanceEditField            matlab.ui.control.NumericEditField
        BreakdownVoltageEditFieldLabel  matlab.ui.control.Label
        BreakdownVoltageEditField       matlab.ui.control.NumericEditField
        LeakageCurrentEditFieldLabel    matlab.ui.control.Label
        LeakageCurrentEditField         matlab.ui.control.NumericEditField
        InputsLabel                     matlab.ui.control.Label
        OutputsLabel                    matlab.ui.control.Label
        DielectricPCSDropDownLabel      matlab.ui.control.Label
        DielectricPCSDropDown           matlab.ui.control.DropDown
        FrequencyPCSEditFieldLabel      matlab.ui.control.Label
        FrequencyPCSEditField           matlab.ui.control.NumericEditField
        UIAxes2                         matlab.ui.control.UIAxes
        ConductorPCSDropDownLabel       matlab.ui.control.Label
        ConductorPCSDropDown            matlab.ui.control.DropDown
        UIAxes2_2                       matlab.ui.control.UIAxes
        ReactanceEditFieldLabel         matlab.ui.control.Label
        ReactanceEditField              matlab.ui.control.NumericEditField
        ValuesForTestingButton          matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetValuesButton
        function GetValuesButtonPushed(app, event)
                %define variables
                d_material = app.DielectricPCSDropDown.Value  %XXX
                c_material = app.ConductorPCSDropDown.Value
                length = app.LengthPEditField.Value;
                width = app.WidthPEditField.Value;
                distance = app.DistancePCSEditField.Value;
                voltage = app.VoltagePCSEditField.Value;
                height = app.HeightCEditField.Value;
                freq = app.FrequencyPCSEditField.Value;
                inner_radius = app.InnerRadiusCSEditField.Value;
                outer_radius = app.OuterRadiusCSEditField.Value;
                %define constants table
                e0 = 8.854e-12;
                dielectric_constants = readtable('Dielectric_Table.xlsx');
                disp(dielectric_constants);
                names = ["Air", "Alumina", "Barium Titanate", "Glass", "Ice", "Mica", "Polyethylene", "Polystyrene", "Quartz (fused)", "Silicon", "Soil", "Teflon", "Water (distilled)","Aluminum","Carbon","Copper","Gold","Graphite","Iron","Lead","Nichrome","Nickel","Silver","Solder","Stainless steel","Tin","Tungsten"];
                for i = 1:27
                    if names(i) == d_material
                        d_index = i;
                    end
                    if names(i) == c_material
                        c_index = i;
                    end
                    
                end
                d_info = table2array(dielectric_constants(d_index,2:4));
                x = 0:distance/100:distance*2;
                %call each shape
                if app.ParallelPlateButton.Value
                    %calculate capacitance
                    app.CapacitanceEditField.Value = e0*d_info(1)*length*width/distance;
                    %calculate conductance
                    app.ConductanceEditField.Value = d_info(3)*length*width/distance;
                    %calcualte leakage current
                    app.LeakageCurrentEditField.Value = d_info(3)*voltage*length*width/distance;
                    %calcultae vbr
                    app.BreakdownVoltageEditField.Value = distance*d_info(2);
                    %calcultae reactance
                    app.ReactanceEditField.Value = 1/(freq*(e0*d_info(1)*length*width/distance));
                    %plot
                    plot(app.UIAxes, x, d_info(3)/(d_info(1)*e0), '-r');
                    axis(app.UIAxes, 'tight')
                end
                if app.CylindricalButton.Value
                    %calculate capacitance
                    app.CapacitanceEditField.Value = 2*pi*d_info(1)*e0*height/(log(outer_radius/inner_radius));
                    %calculate conductance
                    app.ConductanceEditField.Value = d_info(3)*height;
                    %calcualte leakage current
                    app.LeakageCurrentEditField.Value = d_info(3)*height*voltage;
                    %calcultae vbr
                    app.BreakdownVoltageEditField.Value = distance*d_info(2);
                    %calcultae reactance
                    app.ReactanceEditField.Value = 2*pi*d_info(1)*e0*height/(log(outer_radius/inner_radius));
                end
                if app.SphericalButton.Value
                    %calculate capacitance
                    app.CapacitanceEditField.Value = d_info(1)*4*pi*e0/(1/inner_radius - 1/outer_radius);
                    %calculate conductance
                    app.ConductanceEditField.Value = d_info(3)*height;
                    %calcualte leakage current
                    app.LeakageCurrentEditField.Value = d_info(3)*height*voltage;
                    %calcultae vbr
                    app.BreakdownVoltageEditField.Value = distance*d_info(2);
                    %calcultae reactance
                app.ReactanceEditField.Value = d_info(1)*4*pi*e0/(1/inner_radius - 1/outer_radius);
                end
                
                
                %{
                num = [0.5]; % Define numerator of G(s).
                den = [1 0]; % Define denominator of G(s).
                G = tf(num,den) % Create and display G(s).
                plot(bode(app.UIAxes2,G)); % Draw Bode diagram. Or use  bode(numG,denG);

                %plot(app.UIAxes,y(:,:,1));
                %}
             
        end

        % Button pushed function: ValuesForTestingButton
        function ValuesForTestingButtonPushed(app, event)
            material = 'Air';  %XXX
                length = .3;
                width = .1;
                distance = .01;
                voltage = 5;
                height = .3;
                freq = 60;
                inner_radius = .01;
                outer_radius = .015;
                
                 app.DielectricPCSDropDown.Value  =material;
                 app.LengthPEditField.Value = length;
                 app.WidthPEditField.Value = width;
                app.DistancePCSEditField.Value = distance;
                app.VoltagePCSEditField.Value = voltage;
                app.HeightCEditField.Value = height;
                 app.FrequencyPCSEditField.Value = freq;
                app.InnerRadiusCSEditField.Value = inner_radius;
                app.OuterRadiusCSEditField.Value = outer_radius;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'E-Field vs Distance')
            xlabel(app.UIAxes, 'Distance [units]')
            ylabel(app.UIAxes, 'E-Field')
            zlabel(app.UIAxes, 'Z')
            app.CapacitorTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.CapacitorTypeButtonGroup.Title = 'Capacitor Type';
            app.ParallelPlateButton = uiradiobutton(app.CapacitorTypeButtonGroup);
            app.ParallelPlateButton.Text = 'Parallel Plate';
            app.ParallelPlateButton.Value = true;
            app.CylindricalButton = uiradiobutton(app.CapacitorTypeButtonGroup);
            app.CylindricalButton.Text = 'Cylindrical';
            app.SphericalButton = uiradiobutton(app.CapacitorTypeButtonGroup);
            app.SphericalButton.Text = 'Spherical';
            app.DistancePCSEditFieldLabel = uilabel(app.UIFigure);
            app.DistancePCSEditFieldLabel.Text = 'Distance (P, C, S)';
            app.DistancePCSEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoltagePCSLabel = uilabel(app.UIFigure);
            app.VoltagePCSLabel.Text = 'Voltage (P, C, S)';
            app.VoltagePCSEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthPEditFieldLabel = uilabel(app.UIFigure);
            app.LengthPEditFieldLabel.Text = 'Length (P)';
            app.LengthPEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthPEditFieldLabel = uilabel(app.UIFigure);
            app.WidthPEditFieldLabel.Text = 'Width (P)';
            app.WidthPEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightCEditFieldLabel = uilabel(app.UIFigure);
            app.HeightCEditFieldLabel.Text = 'Height (C)';
            app.HeightCEditField = uieditfield(app.UIFigure, 'numeric');
            app.InnerRadiusCSEditFieldLabel = uilabel(app.UIFigure);
            app.InnerRadiusCSEditFieldLabel.Text = 'Inner Radius (C, S)';
            app.InnerRadiusCSEditField = uieditfield(app.UIFigure, 'numeric');
            app.OuterRadiusCSEditFieldLabel = uilabel(app.UIFigure);
            app.OuterRadiusCSEditFieldLabel.Text = 'Outer Radius (C, S)';
            app.OuterRadiusCSEditField = uieditfield(app.UIFigure, 'numeric');
            app.GetValuesButton = uibutton(app.UIFigure, 'push');
            app.GetValuesButton.ButtonPushedFcn = createCallbackFcn(app, @GetValuesButtonPushed, true);
            app.GetValuesButton.Text = 'Get Values';
            app.CapacitanceEditFieldLabel = uilabel(app.UIFigure);
            app.CapacitanceEditFieldLabel.Text = 'Capacitance';
            app.CapacitanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConductanceEditFieldLabel = uilabel(app.UIFigure);
            app.ConductanceEditFieldLabel.Text = 'Conductance';
            app.ConductanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.BreakdownVoltageEditFieldLabel = uilabel(app.UIFigure);
            app.BreakdownVoltageEditFieldLabel.Text = 'Breakdown Voltage';
            app.BreakdownVoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeakageCurrentEditFieldLabel = uilabel(app.UIFigure);
            app.LeakageCurrentEditFieldLabel.Text = 'Leakage Current';
            app.LeakageCurrentEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputsLabel = uilabel(app.UIFigure);
            app.InputsLabel.Text = 'Inputs';
            app.OutputsLabel = uilabel(app.UIFigure);
            app.OutputsLabel.Text = 'Outputs';
            app.DielectricPCSDropDownLabel = uilabel(app.UIFigure);
            app.DielectricPCSDropDownLabel.Text = 'Dielectric (P, C, S)';
            app.DielectricPCSDropDown = uidropdown(app.UIFigure);
            app.DielectricPCSDropDown.Items = {'Air', 'Alumina', 'Barium Titanate', 'Glass', 'Ice', 'Mica', 'Polyethylene', 'Polystyrene', 'Quartz (fused)', 'Silicon', 'Soil', 'Teflon', 'Water (distilled)', 'Seawater'};
            app.DielectricPCSDropDown.Value = 'Air';
            app.FrequencyPCSEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyPCSEditFieldLabel.Text = 'Frequency (P, C, S)';
            app.FrequencyPCSEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Bode')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.ConductorPCSDropDownLabel = uilabel(app.UIFigure);
            app.ConductorPCSDropDownLabel.Text = 'Conductor (P, C, S)';
            app.ConductorPCSDropDown = uidropdown(app.UIFigure);
            app.ConductorPCSDropDown.Items = {'Aluminum', 'Carbon', 'Copper', 'Gold', 'Graphite', 'Iron', 'Lead', 'Nichrome', 'Nickel', 'Silver', 'Solder', 'Stainless Steel', 'Tin', 'Tungston'};
            app.ConductorPCSDropDown.Value = 'Aluminum';
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Bode Plot')
            xlabel(app.UIAxes2_2, 'w [rad/s]')
            ylabel(app.UIAxes2_2, 'Magnitude/Phase')
            app.ReactanceEditFieldLabel = uilabel(app.UIFigure);
            app.ReactanceEditFieldLabel.Text = 'Reactance';
            app.ReactanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValuesForTestingButton = uibutton(app.UIFigure, 'push');
            app.ValuesForTestingButton.ButtonPushedFcn = createCallbackFcn(app, @ValuesForTestingButtonPushed, true);
            app.ValuesForTestingButton.Text = 'Values For Testing';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CapacitorStatics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end